classdef Final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Button_2    matlab.ui.control.Button
        Button_3    matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        Button      matlab.ui.control.Button
        UITable     matlab.ui.control.Table
        EditField   matlab.ui.control.EditField
        Label       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Visible = 'off';
            app.Button_2.Visible = 'off';
            app.Button_3.Visible = 'off';
            app.EditField.Visible = 'off';
            app.Label.Visible = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {562, 562};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
                [file,path] = uigetfile({'*.xlsx', '*.xls'});
                if file ~= 0
                    Tab = readtable([path file]);
                    global a_Tab;
                    a_Tab = table2cell(Tab);
                    for i = 1:length(a_Tab(:, 1))
                        for j = 1:length(a_Tab(:, 1))
                            if strfind(string(a_Tab(j, 1)), '!')
                            else
                                ctrl_1 = sprintf('%s%s%s', string(a_Tab(j, 2)), string(a_Tab(j, 3)), string(a_Tab(j, 4)))
                                ctrl_2 = sprintf('%s%s%s', string(a_Tab(i, 2)), string(a_Tab(i, 3)), string(a_Tab(i, 4)))
                                if length(ctrl_1) == length(ctrl_2)
                                    if ctrl_1 == ctrl_2
                                        if (string(a_Tab(i, 1)) == string(a_Tab(j, 1))) && (i ~= j)
                                            a_Tab(j, 1) = {[sprintf('! %s', string(a_Tab(j, 1)))]};
                                        end
                                    end
                                end
                            end 
                        end
                    end
                    Tab = cell2table(a_Tab);
                    app.UITable.ColumnName = {'Name','Math','Russian','IT'};
                    app.UITable.Data = Tab;
                    app.UITable.Visible = 'on';
                    app.Button_2.Visible = 'on';
                    app.Button_3.Visible = 'on';
                    app.EditField.Visible = 'on';
                    app.Label.Visible = 'on';
                end
            catch exception
                str = sprintf(['Something is wrong. \n' 'Matlab says: \n' exception.message]);
                uialert(app.UIFigure, str, 'Ooops...');
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            try
                global a_Tab
                i = 1;
                while i <= length(a_Tab(:,1))
                   temp = string(a_Tab(i, 1));
                   if strfind(temp, '!')
                       a_Tab(i, :) = [];
                   else
                       i = i+1;
                   end
                end
                Tab = cell2table(a_Tab);
                app.UITable.Data = Tab;
            catch exception
                str = sprintf(['Something is wrong. \n' 'Matlab says: \n' exception.message]);
                uialert(app.UIFigure, str, 'Ooops...');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            try
                [file,path] = uiputfile('*.xlsx');
                if file ~= 0
                    filename = fullfile(path,file);
                    xlswrite(filename, table2cell(app.UITable.Data));
                end
            catch exception
                str = sprintf(['Something is wrong. \n' 'Matlab says: \n' exception.message]);
                uialert(app.UIFigure, str, 'Ooops...');
            end
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            try
                global a_Tab
                changingValue = event.Value;
                if event.Value
                    temp_Tab = a_Tab;
                    i = 1;
                    while i <= length(temp_Tab(:,1))
                       temp = string(temp_Tab(i, 1));
                       if strfind(temp, event.Value)
                           i = i+1;
                       else
                           temp_Tab(i, :) = [];
                       end
                    end
                    Tab = cell2table(temp_Tab);
                else
                    Tab = cell2table(a_Tab);
                end
                app.UITable.Data = Tab;
            catch exception
                str = sprintf(['Something is wrong. \n' 'Matlab says: \n' exception.message]);
                uialert(app.UIFigure, str, 'Ooops...');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Удалить дубликаты';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Сохранить таблицу';
            app.RightPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.RightPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Загрузить';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = 'Поиск по имени';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end