classdef task1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        EditField     matlab.ui.control.EditField
        Label         matlab.ui.control.Label
        EditField_2   matlab.ui.control.EditField
        EditField_3   matlab.ui.control.EditField
        EditField_4   matlab.ui.control.EditField
        Label_2       matlab.ui.control.Label
        EditField_5   matlab.ui.control.EditField
        EditField_6   matlab.ui.control.EditField
        EditField_7   matlab.ui.control.EditField
        Label_3       matlab.ui.control.Label
        EditField_8   matlab.ui.control.EditField
        EditField_9   matlab.ui.control.EditField
        EditField_10  matlab.ui.control.EditField
        Label_4       matlab.ui.control.Label
        Label_5       matlab.ui.control.Label
        Label_6       matlab.ui.control.Label
        pushmeButton  matlab.ui.control.Button
        Label_7       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pushmeButton
        function pushmeButtonPushed(app, event)
            s1 = str2num(app.EditField_8.Value) 
            s2 = str2num(app.EditField_9.Value) 
            s3 = str2num(app.EditField_10.Value) 
            need_s = str2num(app.EditField.Value)
            s4 = s1 + s2 + s3
            s5 = num2str(s4)
            app.Label_5.Text = s5
            s6 = 'Поздравляем!!!'
            s7 = 'Увы, вы не прошли :('
            if s4 >= need_s
                app.Label_6.Text = s6
            else
                app.Label_6.Text = s7
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, имя, отчество';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы ЕГЭ';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Сумма баллов:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.pushmeButton = uibutton(app.UIFigure, 'push');
            app.pushmeButton.ButtonPushedFcn = createCallbackFcn(app, @pushmeButtonPushed, true);
            app.pushmeButton.Text = 'push me';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Проходной балл';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end