classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIAxes      matlab.ui.control.UIAxes
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        Button      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.reset
            v1 = app.CheckBox.Value
            v2 = app.CheckBox_2.Value
            x = [0:0.01:4*pi]
            y1 = sin(x)
            y2 = cos(x)
            if v1 & v2
                plot(app.UIAxes, y1)
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, y2)
                hold(app.UIAxes, 'off')
            elseif v2
                plot(app.UIAxes,y2)
            elseif v1
                plot(app.UIAxes,y1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'График синуса';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = 'График косинуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычислить';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end